classdef improved_sar_sim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FMCWMIMOSARSimulatorLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        FMCWChirpParametersPanel        matlab.ui.container.Panel
        StartingFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        StartingFrequencyGHzEditField   matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        SamplingFrequencykspsEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencykspsEditField  matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        CenterFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        CenterFrequencyGHzEditField     matlab.ui.control.NumericEditField
        MaximumBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        MaximumBandwidthGHzEditField    matlab.ui.control.NumericEditField
        TotalBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        TotalBandwidthGHzEditField      matlab.ui.control.NumericEditField
        WavelengthmEditFieldLabel       matlab.ui.control.Label
        WavelengthmEditField            matlab.ui.control.NumericEditField
        MaximumRangemEditFieldLabel     matlab.ui.control.Label
        MaximumRangemEditField          matlab.ui.control.NumericEditField
        RangeResolutionmEditFieldLabel  matlab.ui.control.Label
        RangeResolutionmEditField       matlab.ui.control.NumericEditField
        UsefulBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        UsefulBandwidthGHzEditField     matlab.ui.control.NumericEditField
        TransmitterElementsPanel        matlab.ui.container.Panel
        TxTable                         matlab.ui.control.Table
        AddTxButton                     matlab.ui.control.Button
        RemoveTxButton                  matlab.ui.control.Button
        TransmitterZmEditFieldLabel     matlab.ui.control.Label
        TransmitterZmEditField          matlab.ui.control.NumericEditField
        ReceiverElementsPanel           matlab.ui.container.Panel
        RxTable                         matlab.ui.control.Table
        AddRxButton                     matlab.ui.control.Button
        RemoveRxButton                  matlab.ui.control.Button
        ReceiverZmEditFieldLabel        matlab.ui.control.Label
        ReceiverZmEditField             matlab.ui.control.NumericEditField
        SARSetupPanel                   matlab.ui.container.Panel
        Tab2                            matlab.ui.container.Tab
        CreatedbyJosiahWSmithLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Params
        fmcw
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("../"));
            
            % Create the Tx Table
            app.TxTable.Data = table;
            app.TxTable.ColumnEditable = true;
            
            % Create the Rx Table
            app.RxTable.Data = table;
            app.RxTable.ColumnEditable = true;
        end

        % Value changed function: StartingFrequencyGHzEditField
        function StartingFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: FreqSlopeMHzusEditField
        function FreqSlopeMHzusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: IdleTimeusEditField
        function IdleTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: TXStartTimeusEditField
        function TXStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: ADCStartTimeusEditField
        function ADCStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: SamplingFrequencykspsEditField
        function SamplingFrequencykspsEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: RampEndTimeusEditField
        function RampEndTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: CenterFrequencyGHzEditField
        function CenterFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: MaximumBandwidthGHzEditField
        function MaximumBandwidthGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Button pushed function: AddTxButton
        function AddTxButtonPushed(app, event)
            app.TxTable.Data(end+1,:) = {0,0,false};
        end

        % Button pushed function: RemoveTxButton
        function RemoveTxButtonPushed(app, event)
            app.TxTable.Data = app.TxTable.Data(1:end-1,:);
        end

        % Button pushed function: AddRxButton
        function AddRxButtonPushed(app, event)
            app.RxTable.Data(end+1,:) = {0,0,false};
        end

        % Button pushed function: RemoveRxButton
        function RemoveRxButtonPushed(app, event)
            app.RxTable.Data = app.RxTable.Data(1:end-1,:);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMCWMIMOSARSimulatorLabel = uilabel(app.UIFigure);
            app.FMCWMIMOSARSimulatorLabel.Text = 'FMCW MIMO-SAR Simulator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.FMCWChirpParametersPanel = uipanel(app.SetupTab);
            app.FMCWChirpParametersPanel.Title = 'FMCW Chirp Parameters';
            app.StartingFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.StartingFrequencyGHzEditFieldLabel.Text = 'Starting Frequency (GHz)';
            app.StartingFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.StartingFrequencyGHzEditField.Limits = [0 Inf];
            app.StartingFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartingFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartingFrequencyGHzEditFieldValueChanged, true);
            app.StartingFrequencyGHzEditField.Value = 77;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.FreqSlopeMHzusEditField.Limits = [0 Inf];
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.ValueChangedFcn = createCallbackFcn(app, @FreqSlopeMHzusEditFieldValueChanged, true);
            app.FreqSlopeMHzusEditField.Value = 100.036;
            app.IdleTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.IdleTimeusEditField.Limits = [0 Inf];
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @IdleTimeusEditFieldValueChanged, true);
            app.TXStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TXStartTimeusEditField.Limits = [0 Inf];
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.TXStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @TXStartTimeusEditFieldValueChanged, true);
            app.ADCStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCStartTimeusEditField.Limits = [0 Inf];
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ADCStartTimeusEditFieldValueChanged, true);
            app.ADCSamplesEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 79;
            app.SamplingFrequencykspsEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.SamplingFrequencykspsEditFieldLabel.Text = 'Sampling Frequency (ksps)';
            app.SamplingFrequencykspsEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.SamplingFrequencykspsEditField.Limits = [2000 37500];
            app.SamplingFrequencykspsEditField.RoundFractionalValues = 'on';
            app.SamplingFrequencykspsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencykspsEditFieldValueChanged, true);
            app.SamplingFrequencykspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RampEndTimeusEditField.Limits = [0 Inf];
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @RampEndTimeusEditFieldValueChanged, true);
            app.RampEndTimeusEditField.Value = 39.98;
            app.CenterFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.CenterFrequencyGHzEditFieldLabel.Text = 'Center Frequency (GHz)';
            app.CenterFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.CenterFrequencyGHzEditField.Limits = [0 Inf];
            app.CenterFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.CenterFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyGHzEditFieldValueChanged, true);
            app.CenterFrequencyGHzEditField.Value = 79;
            app.MaximumBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumBandwidthGHzEditFieldLabel.Text = 'Maximum Bandwidth (GHz)';
            app.MaximumBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumBandwidthGHzEditField.Limits = [0 Inf];
            app.MaximumBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumBandwidthGHzEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumBandwidthGHzEditFieldValueChanged, true);
            app.MaximumBandwidthGHzEditField.Value = 4;
            app.TotalBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TotalBandwidthGHzEditFieldLabel.Text = 'Total Bandwidth (GHz)';
            app.TotalBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TotalBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.TotalBandwidthGHzEditField.Editable = 'off';
            app.WavelengthmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.WavelengthmEditFieldLabel.Text = 'Wavelength (m)';
            app.WavelengthmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.WavelengthmEditField.ValueDisplayFormat = '%11.3f';
            app.WavelengthmEditField.Editable = 'off';
            app.MaximumRangemEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumRangemEditFieldLabel.Text = 'Maximum Range (m)';
            app.MaximumRangemEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumRangemEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumRangemEditField.Editable = 'off';
            app.RangeResolutionmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RangeResolutionmEditFieldLabel.Text = 'Range Resolution (m)';
            app.RangeResolutionmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RangeResolutionmEditField.Editable = 'off';
            app.UsefulBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.UsefulBandwidthGHzEditFieldLabel.Text = 'Useful Bandwidth (GHz)';
            app.UsefulBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.UsefulBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.UsefulBandwidthGHzEditField.Editable = 'off';
            app.TransmitterElementsPanel = uipanel(app.SetupTab);
            app.TransmitterElementsPanel.Title = 'Transmitter Elements';
            app.TxTable = uitable(app.TransmitterElementsPanel);
            app.TxTable.ColumnName = {'x'; 'y'; 'Enable'};
            app.TxTable.RowName = {};
            app.AddTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.AddTxButton.ButtonPushedFcn = createCallbackFcn(app, @AddTxButtonPushed, true);
            app.AddTxButton.Text = 'Add Tx';
            app.RemoveTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.RemoveTxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTxButtonPushed, true);
            app.RemoveTxButton.Text = 'Remove Tx';
            app.TransmitterZmEditFieldLabel = uilabel(app.TransmitterElementsPanel);
            app.TransmitterZmEditFieldLabel.Text = 'Transmitter Z (m)';
            app.TransmitterZmEditField = uieditfield(app.TransmitterElementsPanel, 'numeric');
            app.TransmitterZmEditField.Limits = [0 Inf];
            app.TransmitterZmEditField.ValueDisplayFormat = '%11.3f';
            app.ReceiverElementsPanel = uipanel(app.SetupTab);
            app.ReceiverElementsPanel.Title = 'Receiver Elements';
            app.RxTable = uitable(app.ReceiverElementsPanel);
            app.RxTable.ColumnName = {'x'; 'y'; 'Enable'};
            app.RxTable.RowName = {};
            app.AddRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.AddRxButton.ButtonPushedFcn = createCallbackFcn(app, @AddRxButtonPushed, true);
            app.AddRxButton.Text = 'Add Rx';
            app.RemoveRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.RemoveRxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRxButtonPushed, true);
            app.RemoveRxButton.Text = 'Remove Rx';
            app.ReceiverZmEditFieldLabel = uilabel(app.ReceiverElementsPanel);
            app.ReceiverZmEditFieldLabel.Text = 'Receiver Z (m)';
            app.ReceiverZmEditField = uieditfield(app.ReceiverElementsPanel, 'numeric');
            app.ReceiverZmEditField.Limits = [0 Inf];
            app.ReceiverZmEditField.ValueDisplayFormat = '%11.3f';
            app.SARSetupPanel = uipanel(app.SetupTab);
            app.SARSetupPanel.Title = 'SAR Setup';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.CreatedbyJosiahWSmithLabel = uilabel(app.UIFigure);
            app.CreatedbyJosiahWSmithLabel.Text = 'Created by: Josiah W. Smith';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = improved_sar_sim_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end